classdef tgrSimpleController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SettingTab                matlab.ui.container.Tab
        Part3EditField            matlab.ui.control.EditField
        Part3EditFieldLabel       matlab.ui.control.Label
        Part2EditField            matlab.ui.control.EditField
        Part2EditFieldLabel       matlab.ui.control.Label
        Part1EditField            matlab.ui.control.EditField
        Part1Label                matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
        HOMEButton                matlab.ui.control.Button
        MQTTTab                   matlab.ui.container.Tab
        MessageEditField          matlab.ui.control.EditField
        MessageEditFieldLabel     matlab.ui.control.Label
        ControlTab                matlab.ui.container.Tab
        Object3Lamp               matlab.ui.control.Lamp
        Object3LampLabel          matlab.ui.control.Label
        Object2Lamp               matlab.ui.control.Lamp
        Object2LampLabel          matlab.ui.control.Label
        Object1Lamp               matlab.ui.control.Lamp
        Object1LampLabel          matlab.ui.control.Label
        ZButton_Neg               matlab.ui.control.Button
        ZButton_Pos               matlab.ui.control.Button
        RobotZposEditField        matlab.ui.control.NumericEditField
        RobotZposEditFieldLabel   matlab.ui.control.Label
        RobotYposEditField        matlab.ui.control.NumericEditField
        RobotYposEditFieldLabel   matlab.ui.control.Label
        RobotXposEditField        matlab.ui.control.NumericEditField
        RobotXposEditFieldLabel   matlab.ui.control.Label
        YButton_Neg               matlab.ui.control.Button
        YButton_Pos               matlab.ui.control.Button
        XButton_Pos               matlab.ui.control.Button
        XButton_Neg               matlab.ui.control.Button
        MQTTPanel                 matlab.ui.container.Panel
        StatusLamp                matlab.ui.control.Lamp
        StatusLampLabel           matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        MQTTbrokerEditField       matlab.ui.control.EditField
        MQTTbrokerEditFieldLabel  matlab.ui.control.Label
        banner_bottom             matlab.ui.control.Image
        banner_top                matlab.ui.control.Image
    end

    
    properties (Access = private)
        mqttClient      % MQTT client
        mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        next_part
    end
    
    methods (Access = private)
        
        function handleMQTTmsg(app,topic,data)
            disp(topic)  
            cmd = jsondecode(data)
            %app.MessageEditField.Value = cmd.gripper;
            if(topic=="tgr2022/siaboat/d1")
                app.MessageEditField.Value = data;
                init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
                kmitlmove(app.robotCell,init_pose,[cmd.dX, cmd.dY, cmd.dZ],30)
                app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4)
                app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4)
                app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4)
            else
                if(cmd.gripper=="on")
                    app.robotCell.PartOnRobot = app.next_part;
                    app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                    if app.next_part ~= 0
                        temp.grasp="ok";
                        json=jsonencode(temp);
                        write(app.mqttClient,'tgr2022/siaboat/temp',json);
                    else app.next_part == 0
                        temp.grasp="fail";
                        json=jsonencode(temp);
                        write(app.mqttClient,'tgr2022/siaboat/temp',json);
                    end
                else
                    if app.robotCell.PartOnRobot ~= 0
                        temp.release="ok";
                        json=jsonencode(temp);
                        write(app.mqttClient,'tgr2022/siaboat/temp',json);
                    else app.robotCell.PartOnRobot== 0
                        temp.release="fail";
                        json=jsonencode(temp);
                        write(app.mqttClient,'tgr2022/siaboat/temp',json);
                    end  
                    init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
                    kmitlmove(app.robotCell,init_pose,[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) 0.31],30)
                    app.RobotZposEditField.Value = 0.26;
                    app.robotCell.PartOnRobot = 0;
                    app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                end
            end
%             dx = cmd.dX;
%             dy = cmd.dY;
%             dz = cmd.dZ;
%             app.RobotXposEditField.Value = dx;
%             app.RobotYposEditField.Value = dy;
%             app.RobotZposEditField.Value = dz;
        end
        
        
        function CheckLamp(app)
            pos_arm=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
            pos_part1 = app.robotCell.Environment.Parts{1}.centerPoint;
            pos_part2 = app.robotCell.Environment.Parts{2}.centerPoint;
            pos_part3 = app.robotCell.Environment.Parts{3}.centerPoint;
            posdiff1=abs(pos_arm-pos_part1)
            posdiff2=abs(pos_arm-pos_part2)
            posdiff3=abs(pos_arm-pos_part3)
            if (posdiff1<=0.1)
                app.Object1Lamp.Color='g'
                app.next_part = 1
            else
                app.Object1Lamp.Color='r'
                app.next_part = 0
            end

            if (posdiff2<=0.1)
                app.Object2Lamp.Color='g'
                app.next_part = 2
            else
                app.Object2Lamp.Color='r'
                app.next_part = 0
            end

            if (posdiff3<=0.1)
                app.Object3Lamp.Color='g'
                app.next_part = 3
            else
                app.Object3Lamp.Color='r'
                app.next_part = 0
            end

        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.StatusLamp.Color = 'r';
            app.MQTTbrokerEditField.Value = "tcp://broker.hivemq.com";
            % initialize robot setting
            app.Part1EditField.Value = "[0.4 -0.1 0.26]";
            app.Part2EditField.Value = "[0.5 -0.15 0.26]"; 
            app.Part3EditField.Value = "[0.52 0 0.26]";
            app.HOMEButton.Enable = "off";
           
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.mqttClient = mqttclient(app.MQTTbrokerEditField.Value);
            if app.mqttClient.Connected
                app.StatusLamp.Color = 'g';
                app.mqttCmdSub = subscribe(app.mqttClient, "tgr2022/siaboat/d1",Callback=@app.handleMQTTmsg);
                app.mqttCmdSub = subscribe(app.mqttClient, "tgr2022/siaboat/GripperSwitch",Callback=@app.handleMQTTmsg);
            end
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
            app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4)
            app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4)
            app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4)
            CheckLamp(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            robot = loadRobotSetting('KINOVAGen3GripperColl.mat'); 
            joint_states = homeConfiguration(robot);
            part1 = str2num(app.Part1EditField.Value);
            part2 = str2num(app.Part2EditField.Value);
            part3 = str2num(app.Part3EditField.Value);
            parts = {part1, part2, part3};
            colors = ["r", "g", "b"];
            try
                env = tgrCreateEnv(parts, colors);
                app.robotCell = tgrRobotEnvInteraction(robot, joint_states, "gripper", env);
                app.robotCell.visualizeStation();
                app.robotCell.visualizeParts();
                app.HOMEButton.Enable = "on";
            catch
                disp("Error from part setting");
            end
        end

        % Button pushed function: XButton_Neg
        function XButton_NegPushed(app, event)
            init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
            kmitlmove(app.robotCell,init_pose,[app.robotCell.CurrentTaskConfig(1,4)-0.05 app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)],5)
            %if(init_pose(1)-0.49 >= app.robotCell.CurrentTaskConfig(1,4) && init_pose(1)-0.51 <= app.robotCell.CurrentTaskConfig(1,4))
            app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4)-0.05;
            %else
            %    kmitlmove(app.robotCell,init_pose,init_pose,10)
            %end
%             temp.dX=app.robotCell.CurrentTaskConfig(1,4)-0.05 ;
%             temp.dY=app.robotCell.CurrentTaskConfig(2,4) ;
%             temp.dZ=app.robotCell.CurrentTaskConfig(3,4);
%             json=jsonencode(temp);
%             write(app.mqttClient,'tgr2022/siaboat/d1',json);
            
        end

        % Callback function: RobotXposEditField, XButton_Pos
        function XButton_PosPushed(app, event)
            init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
            kmitlmove(app.robotCell,init_pose,[app.robotCell.CurrentTaskConfig(1,4)+0.05 app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)],5)
            app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4)+0.05;
            CheckLamp(app);
        end

        % Button pushed function: YButton_Pos
        function YButton_PosPushed(app, event)
            init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
            kmitlmove(app.robotCell,init_pose,[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4)+0.05 app.robotCell.CurrentTaskConfig(3,4)],5)
            app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4)+0.05;
            CheckLamp(app);
        end

        % Callback function: RobotYposEditField, YButton_Neg
        function YButton_NegPushed(app, event)
            init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
            kmitlmove(app.robotCell,init_pose,[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4)-0.05 app.robotCell.CurrentTaskConfig(3,4)],5)
            app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4)-0.05;
            CheckLamp(app);
        end

        % Button pushed function: ZButton_Pos
        function ZButton_PosPushed(app, event)
            init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
            kmitlmove(app.robotCell,init_pose,[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)+0.05],5)
            app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4)+0.05;
            CheckLamp(app);
        end

        % Button pushed function: ZButton_Neg
        function ZButton_NegPushed(app, event)
            init_pose=[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)] 
            kmitlmove(app.robotCell,init_pose,[app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) app.robotCell.CurrentTaskConfig(3,4)-0.05],5)
            app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4)-0.05;
            CheckLamp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.banner_top = uiimage(app.UIFigure);
            app.banner_top.ImageSource = 'banner_top.jpg';
            app.banner_bottom = uiimage(app.UIFigure);
            app.banner_bottom.ImageSource = fullfile(pathToMLAPP, 'banner_bottom.jpg');
            app.MQTTPanel = uipanel(app.UIFigure);
            app.MQTTPanel.Title = 'MQTT';
            app.MQTTbrokerEditFieldLabel = uilabel(app.MQTTPanel);
            app.MQTTbrokerEditFieldLabel.Text = 'MQTT broker';
            app.MQTTbrokerEditField = uieditfield(app.MQTTPanel, 'text');
            app.ConnectButton = uibutton(app.MQTTPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLampLabel = uilabel(app.MQTTPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.MQTTPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.HOMEButton = uibutton(app.SettingTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.STARTButton = uibutton(app.SettingTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Part1Label = uilabel(app.SettingTab);
            app.Part1Label.Text = 'Part 1';
            app.Part1EditField = uieditfield(app.SettingTab, 'text');
            app.Part1EditField.Value = '[0.4 -0.1 0.26]';
            app.Part2EditFieldLabel = uilabel(app.SettingTab);
            app.Part2EditFieldLabel.Text = 'Part 2';
            app.Part2EditField = uieditfield(app.SettingTab, 'text');
            app.Part2EditField.Value = '[0.5 -0.15 0.26]';
            app.Part3EditFieldLabel = uilabel(app.SettingTab);
            app.Part3EditFieldLabel.Text = 'Part 3';
            app.Part3EditField = uieditfield(app.SettingTab, 'text');
            app.Part3EditField.Value = '[0.52 0 0.26]';
            app.MQTTTab = uitab(app.TabGroup);
            app.MQTTTab.Title = 'MQTT';
            app.MessageEditFieldLabel = uilabel(app.MQTTTab);
            app.MessageEditFieldLabel.Text = 'Message:';
            app.MessageEditField = uieditfield(app.MQTTTab, 'text');
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.XButton_Neg = uibutton(app.ControlTab, 'push');
            app.XButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @XButton_NegPushed, true);
            app.XButton_Neg.Text = 'X-';
            app.XButton_Pos = uibutton(app.ControlTab, 'push');
            app.XButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @XButton_PosPushed, true);
            app.XButton_Pos.Text = 'X+';
            app.YButton_Pos = uibutton(app.ControlTab, 'push');
            app.YButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @YButton_PosPushed, true);
            app.YButton_Pos.Text = 'Y+';
            app.YButton_Neg = uibutton(app.ControlTab, 'push');
            app.YButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @YButton_NegPushed, true);
            app.YButton_Neg.Text = 'Y-';
            app.RobotXposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotXposEditFieldLabel.Text = 'Robot X pos';
            app.RobotXposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotXposEditField.ValueChangedFcn = createCallbackFcn(app, @XButton_PosPushed, true);
            app.RobotYposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotYposEditFieldLabel.Text = 'Robot Y pos';
            app.RobotYposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotYposEditField.ValueChangedFcn = createCallbackFcn(app, @YButton_NegPushed, true);
            app.RobotZposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotZposEditFieldLabel.Text = 'Robot Z pos';
            app.RobotZposEditField = uieditfield(app.ControlTab, 'numeric');
            app.ZButton_Pos = uibutton(app.ControlTab, 'push');
            app.ZButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @ZButton_PosPushed, true);
            app.ZButton_Pos.Text = 'Z+';
            app.ZButton_Neg = uibutton(app.ControlTab, 'push');
            app.ZButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @ZButton_NegPushed, true);
            app.ZButton_Neg.Text = 'Z-';
            app.Object1LampLabel = uilabel(app.ControlTab);
            app.Object1LampLabel.Text = 'Object1';
            app.Object1Lamp = uilamp(app.ControlTab);
            app.Object2LampLabel = uilabel(app.ControlTab);
            app.Object2LampLabel.Text = 'Object2';
            app.Object2Lamp = uilamp(app.ControlTab);
            app.Object3LampLabel = uilabel(app.ControlTab);
            app.Object3LampLabel.Text = 'Object3';
            app.Object3Lamp = uilamp(app.ControlTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tgrSimpleController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end