classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        SettingTab       matlab.ui.container.Tab
        SyncSwitch       matlab.ui.control.Switch
        SyncSwitchLabel  matlab.ui.control.Label
        statusLabel      matlab.ui.control.Label
        gloveLamp        matlab.ui.control.Lamp
        gloveLampLabel   matlab.ui.control.Label
        mqttLamp         matlab.ui.control.Lamp
        mqttLampLabel    matlab.ui.control.Label
        Part3Lamp        matlab.ui.control.Lamp
        Part3LampLabel   matlab.ui.control.Label
        Part2Lamp        matlab.ui.control.Lamp
        Part2LampLabel   matlab.ui.control.Label
        Part1Lamp        matlab.ui.control.Lamp
        Part1LampLabel   matlab.ui.control.Label
        HOMEButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqttClient      % MQTT client
        mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        part1Closed     % Closed to part 1
        part2Closed     % Closed to part 2
        part3Closed     % Closed to part 3
        ros2NodeRobot
        ros2Pubhome
        ros2Pub_xyz
        ros2Pub_grasp
        mqttTopic_xyz
        mqttTopic_grasp
        syncstatus
        

    end
    
    methods (Access = private)
        
        function handleMQTTmsg(app,topic,data)
            disp(topic);
            cmd = jsondecode(data);            
            if isfield(cmd, 'dX')
                app.dXEditField.Value = cmd.dX;
                app.dYEditField.Value = cmd.dY;
                app.dZEditField.Value = cmd.dZ;
                app.moveJoints(cmd.dX, cmd.dY, cmd.dZ)
            elseif isfield(cmd, 'gripper')
                if cmd.gripper == "on"
                    if app.part1Closed
                        app.robotCell.PartOnRobot = 1;
                        app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                        write(app.mqttClient, "tgr/status", '{"grasp":"ok"}')
                    else
                        write(app.mqttClient, "tgr/status", '{"grasp":"fail"}')
                    end
                end
            else
                disp(cmd)
            end

        end
        
        function moveJoints(app, dX, dY, dZ)
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            new_task_states = trvec2tform([X+dX, Y+dY, Z+dZ])*axang2tform([0 1 0 pi]);
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, new_task_states, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
            app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4);
            app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4);
            app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4);
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            part1 = str2num(app.Part1EditField.Value) + [0,0,0.05];
            part2 = str2num(app.Part2EditField.Value) + [0,0,0.05];
            part3 = str2num(app.Part3EditField.Value) + [0,0,0.05];
            if norm(part1 - [X,Y,Z]) < 0.05
                app.part1Closed = 1
                app.Part1Lamp.Color = [1.0, 0, 0];
            else 
                app.part1Closed = 0
                app.Part1Lamp.Color = [0, 0.2, 0];
            end
            if norm(part2 - [X,Y,Z]) < 0.05
                app.part2Closed = 1
                app.Part2Lamp.Color = [1.0, 0, 0];
            else 
                app.part2Closed = 0
                app.Part2Lamp.Color = [0, 0.2, 0];
            end
            if norm(part3 - [X,Y,Z]) < 0.05
                app.part3Closed = 1
                app.Part3Lamp.Color = [1.0, 0, 0];
            else 
                app.part3Closed = 0
                app.Part3Lamp.Color = [0, 0.2, 0];
            end                        
        end
        
        function Connectmqtt (app)
            
            app.mqttTopic_xyz = "topgun/team1/geo";
            app.mqttTopic_grasp="topgun/team1/grasp";
            app.mqttClient = mqttclient("tcp://154.215.14.239",Port=1883,Username="KMITL-01",ClientID="KMITL01",Password="KMITL-01")
            if app.mqttClient.Connected
                app.mqttLamp.Color = 'g';
                subscribe(app.mqttClient, app.mqttTopic_xyz, 'Callback', @(topic,msg) app.mqttMsgHandler(topic,msg))
                subscribe(app.mqttClient, app.mqttTopic_grasp, 'Callback', @(topic,msg) app.mqttMsgHandler(topic,msg))
            end
            
        end
        
        function mqttMsgHandler (app, topic, msg)
            
            
            if strcmp(topic, 'topgun/team1/geo') && app.syncstatus
                text = jsondecode(msg);
                rosmsg = ros2message(app.ros2Pub_xyz);
                rosmsg.x = str2double(text.X) ;
                rosmsg.y = str2double(text.Y) ;
                rosmsg.z = str2double(text.Z) ;
                send(app.ros2Pub_xyz, rosmsg);
            elseif strcmp(topic, 'topgun/team1/grasp') %app.syncstatus
                rosmsg = ros2message(app.ros2Pub_grasp);
                rosmsg.data=convertStringsToChars(msg);
                disp(class(msg))
                send(app.ros2Pub_grasp, rosmsg);
                disp('hi')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.mqttLamp.Color = 'r';
            app.Connectmqtt();
            app.syncstatus=0
            app.gloveLamp.Color='r'
            %subscribe(obj.mqttClient, obj.mqttTopic, 'Callback', @(topic,msg) obj.mqttMsgHandler(topic,msg))            
            % initialize status
            app.part1Closed = 0;
            app.part2Closed = 0;
            app.part3Closed = 0;
            app.Part1Lamp.Color = [0, 0.2, 0];
            app.Part2Lamp.Color = [0, 0.2, 0];
            app.Part3Lamp.Color = [0, 0.2, 0];
            %rosinit
            app.ros2NodeRobot = ros2node('Robot');
            app.ros2Pubhome = ros2publisher(app.ros2NodeRobot, 'home', "std_msgs/String");
            app.ros2Pub_xyz = ros2publisher(app.ros2NodeRobot, 'xyz', "geometry_msgs/Vector3");
            app.ros2Pub_grasp = ros2publisher(app.ros2NodeRobot, 'grasp', "std_msgs/String");
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            rosmsg = ros2message(app.ros2Pubhome)
            rosmsg.data = convertStringsToChars("ok")
            send(app.ros2Pubhome, rosmsg);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Value changed function: SyncSwitch
        function SyncSwitchValueChanged(app, event)
            value = app.SyncSwitch.Value;
            if strcmp(value, 'On')
                app.syncstatus=1
                app.gloveLamp.Color='g'
            else
                app.syncstatus=0
                app.gloveLamp.Color='r'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.HOMEButton = uibutton(app.SettingTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.Part1LampLabel = uilabel(app.SettingTab);
            app.Part1LampLabel.Text = 'Part 1';
            app.Part1Lamp = uilamp(app.SettingTab);
            app.Part2LampLabel = uilabel(app.SettingTab);
            app.Part2LampLabel.Text = 'Part 2';
            app.Part2Lamp = uilamp(app.SettingTab);
            app.Part3LampLabel = uilabel(app.SettingTab);
            app.Part3LampLabel.Text = 'Part 3';
            app.Part3Lamp = uilamp(app.SettingTab);
            app.mqttLampLabel = uilabel(app.SettingTab);
            app.mqttLampLabel.Text = 'mqtt';
            app.mqttLamp = uilamp(app.SettingTab);
            app.gloveLampLabel = uilabel(app.SettingTab);
            app.gloveLampLabel.Text = 'glove';
            app.gloveLamp = uilamp(app.SettingTab);
            app.statusLabel = uilabel(app.SettingTab);
            app.statusLabel.Text = 'status';
            app.SyncSwitchLabel = uilabel(app.SettingTab);
            app.SyncSwitchLabel.Text = 'Sync.';
            app.SyncSwitch = uiswitch(app.SettingTab, 'slider');
            app.SyncSwitch.ValueChangedFcn = createCallbackFcn(app, @SyncSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end