classdef Robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditField        matlab.ui.control.EditField
        EditFieldLabel   matlab.ui.control.Label
        RobotPanel       matlab.ui.container.Panel
        STARTButton      matlab.ui.control.Button
        StatusLamp       matlab.ui.control.Lamp
        StatusLampLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqttClient      % MQTT client
        mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        part1Closed     % Closed to part 1
        part2Closed     % Closed to part 2
        part3Closed     % Closed to part 3
        ros2NodeRobot
        ros2Subhome
        ros2Subxyz
        ros2Subgrasp
    end
    
    methods (Access = private)
        
        function handleMQTTmsg(app,topic,data)
            disp(topic);
            cmd = jsondecode(data);            
            if isfield(cmd, 'dX')
                app.dXEditField.Value = cmd.dX;
                app.dYEditField.Value = cmd.dY;
                app.dZEditField.Value = cmd.dZ;
                app.moveJoints(cmd.dX, cmd.dY, cmd.dZ)
            elseif isfield(cmd, 'gripper')
                if cmd.gripper == "on"
                    if app.part1Closed
                        app.robotCell.PartOnRobot = 1;
                        app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                        write(app.mqttClient, "tgr/status", '{"grasp":"ok"}')
                    else
                        write(app.mqttClient, "tgr/status", '{"grasp":"fail"}')
                    end
                end
            else
                disp(cmd)
            end

        end
        
        function moveJoints(app, dX, dY, dZ)
            disp(app.robotCell)
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            new_task_states = trvec2tform([X+dX, Y+dY, Z+dZ])*axang2tform([0 1 0 pi]);
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, new_task_states, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
%             app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4);
%             app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4);
%             app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4);
%             X = app.robotCell.CurrentTaskConfig(1,4);
%             Y = app.robotCell.CurrentTaskConfig(2,4);
%             Z = app.robotCell.CurrentTaskConfig(3,4);
%             part1 = str2num(app.Part1EditField.Value) + [0,0,0.05];
%             part2 = str2num(app.Part2EditField.Value) + [0,0,0.05];
%             part3 = str2num(app.Part3EditField.Value) + [0,0,0.05];
%             if norm(part1 - [X,Y,Z]) < 0.05
%                 app.part1Closed = 1
%                 app.Close1Lamp.Color = [1.0, 0, 0];
%             else 
%                 app.part1Closed = 0
%                 app.Close1Lamp.Color = [0, 0.2, 0];
%             end
%             if norm(part2 - [X,Y,Z]) < 0.05
%                 app.part2Closed = 1
%                 app.Close2Lamp.Color = [1.0, 0, 0];
%             else 
%                 app.part2Closed = 0
%                 app.Close2Lamp.Color = [0, 0.2, 0];
%             end
%             if norm(part3 - [X,Y,Z]) < 0.05
%                 app.part3Closed = 1
%                 app.Close3Lamp.Color = [1.0, 0, 0];
%             else 
%                 app.part3Closed = 0
%                 app.Close3Lamp.Color = [0, 0.2, 0];
%             end                        
        end
        
        function Home(app)
             for i = 1:100
                app.robotCell.moveJoints([0 0 0 pi*i/200 0 pi*i/200 0] );
             end
        end
        
        
        function rosMsgHomeHandler (app,rosmsg)
            app.Home()
            
        end
        
        function rosMsgxyzHandler (app,rosmsg)
            %disp(rosmsg.x); 
            if rosmsg.y<=-0.30 && app.robotCell.CurrentTaskConfig(1,4)>=-0.5
                kmitlmove(app.robotCell,[-0.01 0 0]);
            elseif rosmsg.y>=0.30 && app.robotCell.CurrentTaskConfig(1,4)<=0.5
                kmitlmove(app.robotCell,[0.01 0 0]);
            elseif rosmsg.z>=0.30 && app.robotCell.CurrentTaskConfig(2,4)>=-0.5
                kmitlmove(app.robotCell,[0 -0.01 0]);
            elseif rosmsg.z<=-0.30 && app.robotCell.CurrentTaskConfig(2,4)<=0.5
                kmitlmove(app.robotCell,[0 0.01 0]);
            elseif rosmsg.x<=-0.30 && app.robotCell.CurrentTaskConfig(3,4)<=0.5
                kmitlmove(app.robotCell,[0 0 0.01]);
            elseif rosmsg.x>=0.30 && app.robotCell.CurrentTaskConfig(1,4)>=-0.5
                kmitlmove(app.robotCell,[0 0 -0.01]);
            end
            
        end


        function rosMsggraspHandler (app,rosmsg)
            if rosmsg=='1'
                app.robotCell.PartOnRobot = 1;
            else
                app.robotCell.PartOnRobot = 0;
            end    
        end

        function checklamp(app)
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            part1 = str2num(app.Part1EditField.Value) + [0,0,0.05];
            part2 = str2num(app.Part2EditField.Value) + [0,0,0.05];
            part3 = str2num(app.Part3EditField.Value) + [0,0,0.05];
            if norm(part1 - [X,Y,Z]) < 0.05
                app.part1Closed = 1
                app.Close1Lamp.Color = [1.0, 0, 0];
            else 
                app.part1Closed = 0
                app.Close1Lamp.Color = [0, 0.2, 0];
            end
            if norm(part2 - [X,Y,Z]) < 0.05
                app.part2Closed = 1
                app.Close2Lamp.Color = [1.0, 0, 0];
            else 
                app.part2Closed = 0
                app.Close2Lamp.Color = [0, 0.2, 0];
            end
            if norm(part3 - [X,Y,Z]) < 0.05
                app.part3Closed = 1
                app.Close3Lamp.Color = [1.0, 0, 0];
            else 
                app.part3Closed = 0
                app.Close3Lamp.Color = [0, 0.2, 0];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.StatusLamp.Color = 'r';
            % initialize robot setting
            app.ros2NodeRobot = ros2node('GUI');
            app.ros2Subhome = ros2subscriber(app.ros2NodeRobot, 'home', "std_msgs/String", @app.rosMsgHomeHandler);
            app.ros2Subxyz = ros2subscriber(app.ros2NodeRobot, 'xyz', "geometry_msgs/Vector3", @app.rosMsgxyzHandler);
            app.ros2Subgrasp = ros2subscriber(app.ros2NodeRobot, 'grasp', "std_msgs/String", @app.rosMsggraspHandler);
            
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            robot = loadRobotSetting('KINOVAGen3GripperColl.mat'); 
            joint_states = homeConfiguration(robot);
            part1 = [0.4 0 0.26];
            part2 = [0 0.5 0.26];
            part3 = [0.4 0.5 0.26];
            parts = {part1, part2, part3};
            colors = ["r", "g", "b"];
            app.StatusLamp.Color = 'g';
            try
                env = tgrCreateEnv(parts, colors);
                app.robotCell = tgrRobotEnvInteraction(robot, joint_states, "gripper", env);
                app.robotCell.Environment.Parts{1}.mesh.Pose(3,4)=-0.4
                app.robotCell.visualizeStation();
                app.robotCell.visualizeParts();
                disp(app.robotCell.Environment.Parts{1}.mesh.Pose)
            catch
                disp("Error from part setting");
            end
        end

        % Callback function
        function XButton_PosPushed(app, event)
            app.moveJoints(0.05, 0, 0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RobotPanel = uipanel(app.UIFigure);
            app.RobotPanel.Title = 'Robot';
            app.StatusLampLabel = uilabel(app.RobotPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.RobotPanel);
            app.STARTButton = uibutton(app.RobotPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end