classdef START < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StatusLamp   matlab.ui.control.Lamp
        Label_2      matlab.ui.control.Label
        Label        matlab.ui.control.Label
        STARTButton  matlab.ui.control.Button
        Image7       matlab.ui.control.Image
        Image2       matlab.ui.control.Image
        Image        matlab.ui.control.Image
    end

    
    properties (Access = private)
        mqttClient      % MQTT client
        mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        partClosed     % Closed to part 1
        ros2NodeRobot
        ros2Subhome
        ros2Subxyz
        ros2Subgrasp
    end
    
    methods (Access = private)
        function handleMQTTmsg(app,topic,data)
            disp(topic);
            cmd = jsondecode(data);            
            if isfield(cmd, 'dX')
                app.dXEditField.Value = cmd.dX;
                app.dYEditField.Value = cmd.dY;
                app.dZEditField.Value = cmd.dZ;
                app.moveJoints(cmd.dX, cmd.dY, cmd.dZ)
            elseif isfield(cmd, 'gripper')
                if cmd.gripper == "on"
                    if app.part1Closed
                        app.robotCell.PartOnRobot = 1;
                        app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                        write(app.mqttClient, "tgr/status", '{"grasp":"ok"}')
                    else
                        write(app.mqttClient, "tgr/status", '{"grasp":"fail"}')
                    end
                end
            else
                disp(cmd)
            end

        end
        
        function moveJoints(app, dX, dY, dZ)
            disp(app.robotCell)
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            new_task_states = trvec2tform([X+dX, Y+dY, Z+dZ])*axang2tform([0 1 0 pi]);
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, new_task_states, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
%             app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4);
%             app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4);
%             app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4);
%             X = app.robotCell.CurrentTaskConfig(1,4);
%             Y = app.robotCell.CurrentTaskConfig(2,4);
%             Z = app.robotCell.CurrentTaskConfig(3,4);
%             part1 = str2num(app.Part1EditField.Value) + [0,0,0.05];
%             part2 = str2num(app.Part2EditField.Value) + [0,0,0.05];
%             part3 = str2num(app.Part3EditField.Value) + [0,0,0.05];
        end
        
        function Home(app)
             for i = 1:100
                app.robotCell.moveJoints([0 0 0 pi*i/200 0 pi*i/200 0] );
             end
        end
        
        
        function rosMsgHomeHandler (app,rosmsg)
            
            app.Home()
            app.move([0.4 0 0.5])
        end
        
        function rosMsgxyzHandler (app,rosmsg)
            %disp(rosmsg.x); 
            if rosmsg.y<=-0.30 && app.robotCell.CurrentTaskConfig(1,4)>=-0.5
                kmitlmove(app.robotCell,[-0.01 0 0]);
            elseif rosmsg.y>=0.30 && app.robotCell.CurrentTaskConfig(1,4)<=0.5
                kmitlmove(app.robotCell,[0.01 0 0]);
            elseif rosmsg.z>=0.30 && app.robotCell.CurrentTaskConfig(2,4)>=-0.5
                kmitlmove(app.robotCell,[0 -0.01 0]);
            elseif rosmsg.z<=-0.30 && app.robotCell.CurrentTaskConfig(2,4)<=0.5
                kmitlmove(app.robotCell,[0 0.01 0]);
            elseif rosmsg.x<=-0.30 && app.robotCell.CurrentTaskConfig(3,4)<=0.5
                kmitlmove(app.robotCell,[0 0 0.01]);
            elseif rosmsg.x>=0.30 && app.robotCell.CurrentTaskConfig(1,4)>=-0.5
                kmitlmove(app.robotCell,[0 0 -0.01]);
            end
            app.checklamp();
        end


        function rosMsggraspHandler (app,rosmsg)
            
            if rosmsg.data=='1'
                app.checklamp();
                disp(app.partClosed)
                app.robotCell.PartOnRobot = app.partClosed;
                app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                
            else
                if app.robotCell.PartOnRobot ~= 0
                    app.move([app.robotCell.CurrentTaskConfig(1,4) app.robotCell.CurrentTaskConfig(2,4) 0.31]);
                    dist('test')
                end
                app.robotCell.PartOnRobot = 0;
                app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
            end    
            
        end

        function checklamp(app)
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            part1 = [app.robotCell.Environment.Parts{1}.mesh.Pose(1,4),app.robotCell.Environment.Parts{1}.mesh.Pose(2,4),(app.robotCell.Environment.Parts{1}.mesh.Pose(3,4)+0.05)];
            part2 = [app.robotCell.Environment.Parts{2}.mesh.Pose(1,4),app.robotCell.Environment.Parts{2}.mesh.Pose(2,4),(app.robotCell.Environment.Parts{2}.mesh.Pose(3,4)+0.05)];
            part3 = [app.robotCell.Environment.Parts{3}.mesh.Pose(1,4),app.robotCell.Environment.Parts{3}.mesh.Pose(2,4),(app.robotCell.Environment.Parts{3}.mesh.Pose(3,4)+0.05)];


            if norm(part1 - [X,Y,Z]) < 0.05
                app.partClosed = 1;
    
            elseif norm(part2 - [X,Y,Z]) < 0.05
                app.partClosed = 2;
        
            elseif norm(part3 - [X,Y,Z]) < 0.05
                app.partClosed = 3;
                
            else 
                app.partClosed = 0;
                
            end
            
        end
       
        
        function  move (app,Finish)
            ik = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, app.robotCell.NumJoints-1);
            for i = 1:100
                init_pose = app.robotCell.CurrentJointConfig;
                pose = app.robotCell.CurrentTaskConfig;
                new_task_states = trvec2tform([pose(1,4)+i*((Finish(1)-pose(1,4))*0.01), pose(2,4)+i*((Finish(2)-pose(2,4))*0.01), pose(3,4)+i*((Finish(3)-pose(3,4))*0.01)])*axang2tform([0 1 0 pi]);
                joint_sols = ik(app.robotCell.RobotEndEffector, new_task_states, weights, init_pose);
                app.robotCell.moveJoints(joint_sols);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.StatusLamp.Color = [0.72 0.72 0.72];
            % initialize robot setting
            app.ros2NodeRobot = ros2node('GUI');
            app.ros2Subhome = ros2subscriber(app.ros2NodeRobot, 'home', "std_msgs/String", @app.rosMsgHomeHandler);
            app.ros2Subxyz = ros2subscriber(app.ros2NodeRobot, 'xyz', "geometry_msgs/Vector3", @app.rosMsgxyzHandler);
            app.ros2Subgrasp = ros2subscriber(app.ros2NodeRobot, 'grasp', "std_msgs/String", @app.rosMsggraspHandler);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            robot = loadRobotSetting('KINOVAGen3GripperColl.mat'); 
            joint_states = homeConfiguration(robot);
            part1 = [0.4 0 0.26];
            part2 = [0.4 -0.25 0.26];
            part3 = [0.4 0.25 0.26];
            parts = {part1, part2, part3};
            colors = ["r", "g", "b"];
            app.StatusLamp.Color = 'g';
            try
                env = tgrCreateEnv(parts, colors);
                app.robotCell = tgrRobotEnvInteraction(robot, joint_states, "gripper", env);
                app.robotCell.visualizeStation();
                app.robotCell.visualizeParts();
                disp(app.robotCell.Environment.Parts{1}.mesh.Pose)
            catch
                disp("Error from part setting");
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'banner tesa.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'haptic_gloves.png');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'bottom_metaverse.png');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Icon = fullfile(pathToMLAPP, 'start_button.png');
            app.STARTButton.Text = 'START';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'ทีม เสี่ยโบ๊ตสั่งลุย';
            app.StatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = START

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end